classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        AppDesignTab                   matlab.ui.container.Tab
        PlotButton                     matlab.ui.control.Button
        CutoffFrequencyEditFieldLabel  matlab.ui.control.Label
        CutoffFrequency                matlab.ui.control.NumericEditField
        FilterOrderEditFieldLabel      matlab.ui.control.Label
        FilterOrder                    matlab.ui.control.NumericEditField
        WindowDropDownLabel            matlab.ui.control.Label
        WindowDropDown                 matlab.ui.control.DropDown
        PassbandRippleEditFieldLabel   matlab.ui.control.Label
        Ap                             matlab.ui.control.NumericEditField
        StopbandAttenLabel             matlab.ui.control.Label
        Ast                            matlab.ui.control.NumericEditField
        StopbandFreqLabel              matlab.ui.control.Label
        Fst                            matlab.ui.control.NumericEditField
        PassbandFreqLabel              matlab.ui.control.Label
        Fp                             matlab.ui.control.NumericEditField
    end

    methods (Access = private)

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            
            Hf = fdesign.lowpass('N,Fc',app.FilterOrder.Value,app.CutoffFrequency.Value);
             
            if app.WindowDropDown.Value == "Hamming"
               Hd1 = design(Hf,'window','window',@hamming,'systemobject',true);
               fvtool(Hd1,'Color','White');
               legend 'Hamming window design' 
               
            elseif app.WindowDropDown.Value == "Chebyshev"
               Hd2 = design(Hf,'window','window',{@chebwin,50},'systemobject',true);
               fvtool(Hd2,'Color','White');
               legend 'Dolph-ev window design Chebysh'
               
            elseif app.WindowDropDown.Value == "Hamming&Cheb"            
               Hd1 = design(Hf,'window','window',@hamming,'systemobject',true);
               Hd2 = design(Hf,'window','window',{@chebwin,50},'systemobject',true);
               fvtool(Hd1,Hd2,'Color','White');
               legend 'Hamming window design' 'Dolph-ev window design Chebysh'
            
            elseif app.WindowDropDown.Value == "Kaiser"         
                Hf = fdesign.lowpass('Fp,Fst,Ap,Ast'...
                    ,app.Fp.Value,app.Fst.Value,app.Ap.Value,app.Ast.Value);
                Hd3 = design(Hf,'kaiserwin','systemobject',true);
                fvtool(Hd3,'Color','White');
                legend 'Kaiser window design'
                
            elseif app.WindowDropDown.Value == "Equiripple"
                Hf = fdesign.lowpass('Fp,Fst,Ap,Ast'...
                    ,app.Fp.Value,app.Fst.Value,app.Ap.Value,app.Ast.Value);
                Hd4 = design(Hf,'equiripple','systemobject',true);
                fvtool(Hd4,'Color','White');
                legend 'Equiripple window design'
            end  
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.AppDesignTab = uitab(app.TabGroup);
            app.AppDesignTab.Title = 'AppDesign';
            app.PlotButton = uibutton(app.AppDesignTab, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.CutoffFrequencyEditFieldLabel = uilabel(app.AppDesignTab);
            app.CutoffFrequencyEditFieldLabel.Text = 'Cutoff Frequency';
            app.CutoffFrequency = uieditfield(app.AppDesignTab, 'numeric');
            app.CutoffFrequency.Limits = [0 1];
            app.CutoffFrequency.Value = 0.4;
            app.FilterOrderEditFieldLabel = uilabel(app.AppDesignTab);
            app.FilterOrderEditFieldLabel.Text = 'Filter Order';
            app.FilterOrder = uieditfield(app.AppDesignTab, 'numeric');
            app.FilterOrder.Limits = [0 Inf];
            app.FilterOrder.Value = 100;
            app.WindowDropDownLabel = uilabel(app.AppDesignTab);
            app.WindowDropDownLabel.Text = 'Window';
            app.WindowDropDown = uidropdown(app.AppDesignTab);
            app.WindowDropDown.Items = {'Hamming', 'Chebyshev', 'Hamming&Cheb', 'Kaiser', 'Equiripple', ''};
            app.WindowDropDown.Value = 'Hamming';
            app.PassbandRippleEditFieldLabel = uilabel(app.AppDesignTab);
            app.PassbandRippleEditFieldLabel.Text = 'Passband Ripple';
            app.Ap = uieditfield(app.AppDesignTab, 'numeric');
            app.Ap.Value = 0.06;
            app.StopbandAttenLabel = uilabel(app.AppDesignTab);
            app.StopbandAttenLabel.Text = 'Stopband Atten';
            app.Ast = uieditfield(app.AppDesignTab, 'numeric');
            app.Ast.Value = 60;
            app.StopbandFreqLabel = uilabel(app.AppDesignTab);
            app.StopbandFreqLabel.Text = 'Stopband Freq';
            app.Fst = uieditfield(app.AppDesignTab, 'numeric');
            app.Fst.Value = 0.42;
            app.PassbandFreqLabel = uilabel(app.AppDesignTab);
            app.PassbandFreqLabel.Text = 'Passband Freq';
            app.Fp = uieditfield(app.AppDesignTab, 'numeric');
            app.Fp.Value = 0.38;
        end
    end

    methods (Access = public)

        % Construct app
        function app = app1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end